classdef EV_calculator_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EVcalculatorUIFigure            matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        EnergyconsumptionTab            matlab.ui.container.Tab
        AverageenergyconsumptionkmWhkmEditField  matlab.ui.control.NumericEditField
        AverageenergyconsumptionkmWhkmEditFieldLabel  matlab.ui.control.Label
        AuxillaryenergyconsumptionWhEditField  matlab.ui.control.NumericEditField
        AuxillaryenergyconsumptionWhEditFieldLabel  matlab.ui.control.Label
        EnergyconsumptionduringconstvelWhEditField  matlab.ui.control.NumericEditField
        EnergyconsumptionduringconstvelWhEditFieldLabel  matlab.ui.control.Label
        EnergyconsumptionduringaccWhEditField  matlab.ui.control.NumericEditField
        EnergyconsumptionduringaccWhEditFieldLabel  matlab.ui.control.Label
        DistancecoveredduringaccdeckmEditField  matlab.ui.control.NumericEditField
        DistancecoveredduringaccdeckmEditFieldLabel  matlab.ui.control.Label
        Constantaccdecms2EditField      matlab.ui.control.NumericEditField
        Constantaccdecms2EditFieldLabel  matlab.ui.control.Label
        GrossvehicleweightkgEditField   matlab.ui.control.NumericEditField
        GrossvehicleweightkgEditFieldLabel  matlab.ui.control.Label
        BatterypackTab                  matlab.ui.container.Tab
        RangeKmEditField                matlab.ui.control.NumericEditField
        RangeKmEditFieldLabel           matlab.ui.control.Label
        PackheatgenerationWEditField    matlab.ui.control.NumericEditField
        PackheatgenerationWEditFieldLabel  matlab.ui.control.Label
        AvailablePackpowerkWEditField   matlab.ui.control.NumericEditField
        AvailablePackpowerkWEditFieldLabel  matlab.ui.control.Label
        AvailablePackenergykWhEditField  matlab.ui.control.NumericEditField
        AvailablePackenergykWhEditFieldLabel  matlab.ui.control.Label
        AvailablePackvoltageVEditField  matlab.ui.control.NumericEditField
        AvailablePackvoltageVEditFieldLabel  matlab.ui.control.Label
        AvailablePackcapacityAhEditField  matlab.ui.control.NumericEditField
        AvailablePackcapacityAhEditFieldLabel  matlab.ui.control.Label
        PackweightkgEditField           matlab.ui.control.NumericEditField
        PackweightkgEditFieldLabel      matlab.ui.control.Label
        PackheigthmmEditField           matlab.ui.control.NumericEditField
        PackheigthmmEditFieldLabel      matlab.ui.control.Label
        Packaream2EditField             matlab.ui.control.NumericEditField
        Packaream2EditFieldLabel        matlab.ui.control.Label
        PackwidthmmEditField            matlab.ui.control.NumericEditField
        PackwidthmmEditFieldLabel       matlab.ui.control.Label
        NoofparallelrowsEditField       matlab.ui.control.NumericEditField
        NoofparallelrowsEditFieldLabel  matlab.ui.control.Label
        TotalcellsEditField             matlab.ui.control.NumericEditField
        TotalcellsEditFieldLabel        matlab.ui.control.Label
        PacklengthmmEditField           matlab.ui.control.NumericEditField
        PacklengthmmEditFieldLabel      matlab.ui.control.Label
        NoofcellsinseriesEditField      matlab.ui.control.NumericEditField
        NoofcellsinseriesEditFieldLabel  matlab.ui.control.Label
        ParametersliderinfluenceTab     matlab.ui.container.Tab
        TotalcellsGauge                 matlab.ui.control.LinearGauge
        TotalcellsGaugeLabel            matlab.ui.control.Label
        PackweightkgGauge               matlab.ui.control.LinearGauge
        PackweightkgGaugeLabel          matlab.ui.control.Label
        PackheatgenerationWGauge        matlab.ui.control.LinearGauge
        PackheatgenerationWGaugeLabel   matlab.ui.control.Label
        RangekmGauge                    matlab.ui.control.LinearGauge
        RangekmGaugeLabel               matlab.ui.control.Label
        RESULTSLabel                    matlab.ui.control.Label
        INPUTPARAMETERSLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CellbatterypackTab              matlab.ui.container.Tab
        PackvoltageVEditField           matlab.ui.control.NumericEditField
        PackvoltageVEditFieldLabel      matlab.ui.control.Label
        PackenergykWhEditField          matlab.ui.control.NumericEditField
        PackenergykWhEditFieldLabel     matlab.ui.control.Label
        CrateEditField                  matlab.ui.control.NumericEditField
        ValuestobeenteredbyuserLabel    matlab.ui.control.Label
        CelltypeButtonGroup             matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.ToggleButton
        Button_2                        matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.ToggleButton
        DischargecurrentAEditField      matlab.ui.control.NumericEditField
        DischargecurrentAEditFieldLabel  matlab.ui.control.Label
        InternalresistancemEditField    matlab.ui.control.NumericEditField
        InternalresistancemEditFieldLabel  matlab.ui.control.Label
        MaxDODEditField                 matlab.ui.control.NumericEditField
        MaxDODEditFieldLabel            matlab.ui.control.Label
        CrateEditFieldLabel             matlab.ui.control.Label
        WeightgmEditField               matlab.ui.control.NumericEditField
        WeightgmEditFieldLabel          matlab.ui.control.Label
        HeightmmEditField               matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel          matlab.ui.control.Label
        DiametermmEditField             matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel        matlab.ui.control.Label
        CapacitymAhEditField            matlab.ui.control.NumericEditField
        CapacitymAhEditFieldLabel       matlab.ui.control.Label
        VoltageVEditField               matlab.ui.control.NumericEditField
        VoltageVEditFieldLabel          matlab.ui.control.Label
        VehicleDrivingstyleTab          matlab.ui.container.Tab
        SymmetricaltrapeziumvtcurvecharacteristicsLabel  matlab.ui.control.Label
        TimetakentoreachtheconstantspeedsecEditField  matlab.ui.control.NumericEditField
        TimetakentoreachtheconstantspeedsecEditFieldLabel  matlab.ui.control.Label
        ConstantspeedkmphEditField      matlab.ui.control.NumericEditField
        ConstantspeedkmphEditFieldLabel  matlab.ui.control.Label
        TotaldistancecoveredkmEditField  matlab.ui.control.NumericEditField
        TotaldistancecoveredkmEditFieldLabel  matlab.ui.control.Label
        ReplacementmotorwtkgEditFieldLabel  matlab.ui.control.Label
        Airdensitykgm3EditField         matlab.ui.control.NumericEditField
        Airdensitykgm3EditFieldLabel    matlab.ui.control.Label
        RoadgradientangledegEditField   matlab.ui.control.NumericEditField
        RoadgradientangledegEditFieldLabel  matlab.ui.control.Label
        VehicleenvironmentparametersLabel  matlab.ui.control.Label
        Vehicleridercrosssectionm2EditField  matlab.ui.control.NumericEditField
        Vehicleridercrosssectionm2EditFieldLabel  matlab.ui.control.Label
        RollingresistancecoefficientEditField  matlab.ui.control.NumericEditField
        RollingresistancecoefficientEditFieldLabel  matlab.ui.control.Label
        DragcoefficientEditField        matlab.ui.control.NumericEditField
        DragcoefficientEditFieldLabel   matlab.ui.control.Label
        NoofpassengersEditField         matlab.ui.control.NumericEditField
        NoofpassengersEditFieldLabel    matlab.ui.control.Label
        PassengerweightkgEditField      matlab.ui.control.NumericEditField
        ReplacementmotorwtkgEditField   matlab.ui.control.NumericEditField
        PassengerweightkgEditFieldLabel  matlab.ui.control.Label
        FueltankenginewtkgEditField     matlab.ui.control.NumericEditField
        FueltankenginewtkgEditFieldLabel  matlab.ui.control.Label
        KerbweightkgEditField           matlab.ui.control.NumericEditField
        KerbweightkgEditFieldLabel      matlab.ui.control.Label
        ParameterslidersTab             matlab.ui.container.Tab
        CellcapacitymAhSlider           matlab.ui.control.Slider
        CellcapacitymAhSliderLabel      matlab.ui.control.Label
        CellvoltageVSlider              matlab.ui.control.Slider
        CellvoltageVSliderLabel         matlab.ui.control.Label
        CellCrateSlider                 matlab.ui.control.Slider
        CellCrateSliderLabel            matlab.ui.control.Label
        PackvoltageVSlider              matlab.ui.control.Slider
        PackvoltageVSliderLabel         matlab.ui.control.Label
        VelocitykmphSlider              matlab.ui.control.Slider
        VelocitykmphSliderLabel         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function updateDischargecurrent(app)
            app.DischargecurrentAEditField.Value = app.CrateEditField.Value*app.CapacitymAhEditField.Value/1000;
            updateAvailPackPow(app)
            updatePackHeatGen(app)
        end
        
        
        function updateGrossWeight(app)
            app.GrossvehicleweightkgEditField.Value = app.KerbweightkgEditField.Value - app.FueltankenginewtkgEditField.Value + app.ReplacementmotorwtkgEditField.Value + app.PassengerweightkgEditField.Value*app.NoofpassengersEditField.Value + app.PackweightkgEditField.Value;
            updateEConsAcc(app)
            updateEConsVel(app)
        end
        
        function updateConstAcc(app)
            app.Constantaccdecms2EditField.Value = (app.ConstantspeedkmphEditField.Value*5/18)/app.TimetakentoreachtheconstantspeedsecEditField.Value;
            updateEConsAcc(app)
        end
        
        function updateDistCovered(app)
            app.DistancecoveredduringaccdeckmEditField.Value = 0.5*app.ConstantspeedkmphEditField.Value*(app.TimetakentoreachtheconstantspeedsecEditField.Value/3600);
            updateEConsAcc(app)
            updateEConsVel(app)
            updateAuxECons(app)
        end
        
        function updateEConsAcc(app)
            app.EnergyconsumptionduringaccWhEditField.Value = (1/3600)*(app.GrossvehicleweightkgEditField.Value*9.81*(app.RollingresistancecoefficientEditField.Value*cosd(app.RoadgradientangledegEditField.Value) + sind(app.RoadgradientangledegEditField.Value)) + 0.5*(app.Airdensitykgm3EditField.Value*app.DragcoefficientEditField.Value*app.Vehicleridercrosssectionm2EditField.Value*((app.ConstantspeedkmphEditField.Value*5/18)/app.TimetakentoreachtheconstantspeedsecEditField.Value*app.DistancecoveredduringaccdeckmEditField.Value*1000)) + app.GrossvehicleweightkgEditField.Value*app.Constantaccdecms2EditField.Value)*app.DistancecoveredduringaccdeckmEditField.Value*1000;
            updateAvgECons(app)
        end
        
        function updateEConsVel(app)
            app.EnergyconsumptionduringconstvelWhEditField.Value = (1/3600)*(app.GrossvehicleweightkgEditField.Value*9.81*(app.RollingresistancecoefficientEditField.Value*cosd(app.RoadgradientangledegEditField.Value) + sind(app.RoadgradientangledegEditField.Value)) + 0.0386*(app.Airdensitykgm3EditField.Value*app.DragcoefficientEditField.Value*app.Vehicleridercrosssectionm2EditField.Value*app.ConstantspeedkmphEditField.Value^2))*(app.TotaldistancecoveredkmEditField.Value - 2*app.DistancecoveredduringaccdeckmEditField.Value)*1000;
            updateAvgECons(app)
        end
        
        function updateAuxECons(app)
            app.AuxillaryenergyconsumptionWhEditField.Value = 268*((2*app.TimetakentoreachtheconstantspeedsecEditField.Value)/3600 + (app.TotaldistancecoveredkmEditField.Value - 2*app.DistancecoveredduringaccdeckmEditField.Value)/app.ConstantspeedkmphEditField.Value);
            updateAvgECons(app)
        end
        
        function updateAvgECons(app)
            app.AverageenergyconsumptionkmWhkmEditField.Value = (app.EnergyconsumptionduringaccWhEditField.Value + app.EnergyconsumptionduringconstvelWhEditField.Value + app.AuxillaryenergyconsumptionWhEditField.Value)/app.TotaldistancecoveredkmEditField.Value/0.9;
            updateRange(app)
        end
        
        function updateCellsSeries(app)
            app.NoofcellsinseriesEditField.Value = ceil(app.PackvoltageVEditField.Value/app.VoltageVEditField.Value);
            updateTotCells(app)
            updatePackW(app)
            updateAvailPackVolt(app)
        end
        
        function updateParalRows(app)
            app.NoofparallelrowsEditField.Value = floor((app.PackenergykWhEditField.Value*1000)/(app.PackvoltageVEditField.Value*app.CapacitymAhEditField.Value/1000*app.MaxDODEditField.Value));
            updateTotCells(app)
            updatePackL(app)
            updateAvailPackCap(app)
            updateAvailPackPow(app)
        end
        
        function updateTotCells(app)
            app.TotalcellsEditField.Value = app.NoofcellsinseriesEditField.Value*app.NoofparallelrowsEditField.Value;
            updatePackWt(app)
            updatePackHeatGen(app)
            app.TotalcellsGauge.Value = app.TotalcellsEditField.Value;
        end
        
        function updatePackL(app)
            app.PacklengthmmEditField.Value = app.DiametermmEditField.Value*app.NoofparallelrowsEditField.Value;
            updatePackA(app)
        end
        
        function updatePackW(app)
            app.PackwidthmmEditField.Value = app.DiametermmEditField.Value*app.NoofcellsinseriesEditField.Value;
            updatePackA(app)
        end
        
        function updatePackH(app)
            app.PackheigthmmEditField.Value = app.HeightmmEditField.Value;
        end
        
        function updatePackA(app)
            app.Packaream2EditField.Value = app.PacklengthmmEditField.Value*app.PackwidthmmEditField.Value/10^6;
        end
        
        function updatePackWt(app)
            app.PackweightkgEditField.Value = app.WeightgmEditField.Value*app.TotalcellsEditField.Value/1000;
            updateGrossWeight(app)
            app.PackweightkgGauge.Value = app.PackweightkgEditField.Value;
        end
        
        function updateAvailPackCap(app)
            app.AvailablePackcapacityAhEditField.Value = app.CapacitymAhEditField.Value*app.MaxDODEditField.Value*app.NoofparallelrowsEditField.Value/1000;
            updateAvailPackE(app)
        end
        
        function updateAvailPackVolt(app)
            app.AvailablePackvoltageVEditField.Value = app.VoltageVEditField.Value*app.NoofcellsinseriesEditField.Value;
            updateAvailPackE(app)
            updateAvailPackPow(app)
        end
        
        function updateAvailPackE(app)
            app.AvailablePackenergykWhEditField.Value = app.AvailablePackcapacityAhEditField.Value*app.AvailablePackvoltageVEditField.Value/1000;
            updateRange(app)
        end
        
        function updateAvailPackPow(app)
            app.AvailablePackpowerkWEditField.Value = (app.AvailablePackvoltageVEditField.Value*app.DischargecurrentAEditField.Value*app.NoofparallelrowsEditField.Value)/1000;
        end
        
        function updatePackHeatGen(app)
            app.PackheatgenerationWEditField.Value = (app.DischargecurrentAEditField.Value^2)*app.InternalresistancemEditField.Value/1000*app.TotalcellsEditField.Value;
            app.PackheatgenerationWGauge.Value = app.PackheatgenerationWEditField.Value;
        end
        
        function updateRange(app)
            app.RangeKmEditField.Value = (app.AvailablePackenergykWhEditField.Value*1000)/app.AverageenergyconsumptionkmWhkmEditField.Value;
            app.RangekmGauge.Value = app.RangeKmEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CrateEditField
        function CrateEditFieldValueChanged(app, event)
            app.CellCrateSlider.Value = app.CrateEditField.Value;
            
            updateDischargecurrent(app)
        end

        % Value changed function: KerbweightkgEditField
        function KerbweightkgEditFieldValueChanged(app, event)
            updateGrossWeight(app)
        end

        % Value changed function: FueltankenginewtkgEditField
        function FueltankenginewtkgEditFieldValueChanged(app, event)
            updateGrossWeight(app)
        end

        % Callback function
        function ReplacementmotorwtkgEditFieldValueChanged(app, event)
            updateGrossWeight(app)            
        end

        % Value changed function: PassengerweightkgEditField
        function PassengerweightkgEditFieldValueChanged(app, event)
            updateGrossWeight(app)
        end

        % Value changed function: NoofpassengersEditField
        function NoofpassengersEditFieldValueChanged(app, event)
           updateGrossWeight(app)
        end

        % Value changed function: ConstantspeedkmphEditField
        function ConstantspeedkmphEditFieldValueChanged(app, event)
            app.VelocitykmphSlider.Value = app.ConstantspeedkmphEditField.Value;
            
            updateConstAcc(app)
            updateDistCovered(app)
            updateEConsAcc(app)
            updateEConsVel(app)
            updateAuxECons(app)
        end

        % Value changed function: 
        % TimetakentoreachtheconstantspeedsecEditField
        function TimetakentoreachtheconstantspeedsecEditFieldValueChanged(app, event)
            updateConstAcc(app)
            updateDistCovered(app)
            updateEConsAcc(app)
            updateAuxECons(app)
        end

        % Value changed function: DragcoefficientEditField
        function DragcoefficientEditFieldValueChanged(app, event)
            updateEConsAcc(app)
            updateEConsVel(app)
        end

        % Value changed function: 
        % RollingresistancecoefficientEditField
        function RollingresistancecoefficientEditFieldValueChanged(app, event)
            updateEConsAcc(app)
            updateEConsVel(app)
        end

        % Value changed function: 
        % Vehicleridercrosssectionm2EditField
        function Vehicleridercrosssectionm2EditFieldValueChanged(app, event)
            updateEConsAcc(app)
            updateEConsVel(app)
        end

        % Value changed function: RoadgradientangledegEditField
        function RoadgradientangledegEditFieldValueChanged(app, event)
            updateEConsAcc(app)
            updateEConsVel(app)
        end

        % Value changed function: Airdensitykgm3EditField
        function Airdensitykgm3EditFieldValueChanged(app, event)
            updateEConsAcc(app)
            updateEConsVel(app)
        end

        % Value changed function: TotaldistancecoveredkmEditField
        function TotaldistancecoveredkmEditFieldValueChanged(app, event)
            updateEConsVel(app)
            updateAuxECons(app)
            updateAvgECons(app)
        end

        % Value changed function: PackvoltageVEditField
        function PackvoltageVEditFieldValueChanged(app, event)
            app.PackvoltageVSlider.Value = app.PackvoltageVEditField.Value;
            
            updateCellsSeries(app)
            updateParalRows(app)
        end

        % Value changed function: PackenergykWhEditField
        function PackenergykWhEditFieldValueChanged(app, event)
            updateParalRows(app)
        end

        % Value changing function: VelocitykmphSlider
        function VelocitykmphSliderValueChanging(app, event)
            VelchangingValue = event.Value;
            app.ConstantspeedkmphEditField.Value = VelchangingValue;
            
            updateConstAcc(app)
            updateDistCovered(app)
            updateEConsAcc(app)
            updateEConsVel(app)
            updateAuxECons(app)
        end

        % Value changing function: PackvoltageVSlider
        function PackvoltageVSliderValueChanging(app, event)
            PackVchangingValue = event.Value;
            app.PackvoltageVEditField.Value = PackVchangingValue;
            
            updateCellsSeries(app)
            updateParalRows(app)
        end

        % Value changing function: CellCrateSlider
        function CellCrateSliderValueChanging(app, event)
            CratechangingValue = event.Value;
            app.CrateEditField.Value = CratechangingValue;
            
            updateDischargecurrent(app)
        end

        % Value changing function: CellvoltageVSlider
        function CellvoltageVSliderValueChanging(app, event)
            CellVchangingValue = event.Value;
            app.VoltageVEditField.Value = CellVchangingValue;
            
            updateCellsSeries(app)
            updateAvailPackVolt(app)
        end

        % Value changing function: CellcapacitymAhSlider
        function CellcapacitymAhSliderValueChanging(app, event)
            CellCchangingValue = event.Value;
            app.CapacitymAhEditField.Value = CellCchangingValue;
            
            updateDischargecurrent(app)
            updateParalRows(app)
            updateAvailPackCap(app)
        end

        % Selection changed function: CelltypeButtonGroup
        function CelltypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CelltypeButtonGroup.SelectedObject;
            if selectedButton == app.Button
                app.VoltageVEditField.Value = 3.6;
                app.CapacitymAhEditField.Value = 3450;
                app.DiametermmEditField.Value = 18.5;
                app.HeightmmEditField.Value = 65.3;
                app.WeightgmEditField.Value = 49.5;
                app.MaxDODEditField.Value = 0.8;
                app.InternalresistancemEditField.Value = 40;
            elseif selectedButton == app.Button_2
                app.VoltageVEditField.Value = 3.7;
                app.CapacitymAhEditField.Value = 5000;
                app.DiametermmEditField.Value = 26.5;
                app.HeightmmEditField.Value = 65.5;
                app.WeightgmEditField.Value = 93;
                app.MaxDODEditField.Value = 0.8;
                app.InternalresistancemEditField.Value = 19;
            else
                app.VoltageVEditField.Value = 3.7;
                app.CapacitymAhEditField.Value = 4000;
                app.DiametermmEditField.Value = 21.8;
                app.HeightmmEditField.Value = 70.1;
                app.WeightgmEditField.Value = 72;
                app.MaxDODEditField.Value = 0.8;
                app.InternalresistancemEditField.Value = 32;
            end
            app.CellvoltageVSlider.Value = app.VoltageVEditField.Value;
            updateCellsSeries(app)
            updateAvailPackVolt(app)
            
            app.CellcapacitymAhSlider.Value = app.CapacitymAhEditField.Value;
            updateDischargecurrent(app)
            updateParalRows(app)
            updateAvailPackCap(app)
            
            updatePackL(app)
            updatePackW(app)
            
            updatePackH(app)
            
            updateGrossWeight(app)
            updatePackWt(app)
            
            updateParalRows(app)
            updateAvailPackCap(app)
            
            updatePackHeatGen(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EVcalculatorUIFigure = uifigure('Visible', 'off');
            app.EVcalculatorUIFigure.Name = 'EV calculator';
            app.TabGroup = uitabgroup(app.EVcalculatorUIFigure);
            app.CellbatterypackTab = uitab(app.TabGroup);
            app.CellbatterypackTab.Title = 'Cell & battery pack';
            app.VoltageVEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.VoltageVEditFieldLabel.Text = 'Voltage(V)';
            app.VoltageVEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.VoltageVEditField.Editable = 'off';
            app.VoltageVEditField.Value = 1;
            app.CapacitymAhEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.CapacitymAhEditFieldLabel.Text = 'Capacity(mAh)';
            app.CapacitymAhEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.CapacitymAhEditField.Editable = 'off';
            app.CapacitymAhEditField.Value = 1;
            app.DiametermmEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.DiametermmEditFieldLabel.Text = 'Diameter(mm)';
            app.DiametermmEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.DiametermmEditField.Editable = 'off';
            app.DiametermmEditField.Value = 1;
            app.HeightmmEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.HeightmmEditFieldLabel.Text = 'Height(mm)';
            app.HeightmmEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.HeightmmEditField.Editable = 'off';
            app.HeightmmEditField.Value = 1;
            app.WeightgmEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.WeightgmEditFieldLabel.Text = 'Weight(gm)';
            app.WeightgmEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.WeightgmEditField.Editable = 'off';
            app.WeightgmEditField.Value = 1;
            app.CrateEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.CrateEditFieldLabel.Text = 'C-rate';
            app.MaxDODEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.MaxDODEditFieldLabel.Text = 'Max % DOD';
            app.MaxDODEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.MaxDODEditField.Editable = 'off';
            app.MaxDODEditField.Value = 1;
            app.InternalresistancemEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.InternalresistancemEditFieldLabel.Text = 'Internal resistance(mΩ)';
            app.InternalresistancemEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.InternalresistancemEditField.Editable = 'off';
            app.InternalresistancemEditField.Value = 1;
            app.DischargecurrentAEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.DischargecurrentAEditFieldLabel.Text = 'Discharge current(A)';
            app.DischargecurrentAEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.DischargecurrentAEditField.Editable = 'off';
            app.DischargecurrentAEditField.Value = 1;
            app.CelltypeButtonGroup = uibuttongroup(app.CellbatterypackTab);
            app.CelltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CelltypeButtonGroupSelectionChanged, true);
            app.CelltypeButtonGroup.Title = 'Cell type';
            app.Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Button.Text = '18650';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.CelltypeButtonGroup);
            app.Button_2.Text = '26650';
            app.Button_3 = uitogglebutton(app.CelltypeButtonGroup);
            app.Button_3.Text = '21700';
            app.ValuestobeenteredbyuserLabel = uilabel(app.CellbatterypackTab);
            app.ValuestobeenteredbyuserLabel.Text = 'Values to be entered by user';
            app.CrateEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.CrateEditField.ValueChangedFcn = createCallbackFcn(app, @CrateEditFieldValueChanged, true);
            app.CrateEditField.Value = 1;
            app.PackenergykWhEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.PackenergykWhEditFieldLabel.Text = 'Pack energy(kWh)';
            app.PackenergykWhEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.PackenergykWhEditField.ValueChangedFcn = createCallbackFcn(app, @PackenergykWhEditFieldValueChanged, true);
            app.PackenergykWhEditField.Value = 1;
            app.PackvoltageVEditFieldLabel = uilabel(app.CellbatterypackTab);
            app.PackvoltageVEditFieldLabel.Text = 'Pack voltage(V)';
            app.PackvoltageVEditField = uieditfield(app.CellbatterypackTab, 'numeric');
            app.PackvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @PackvoltageVEditFieldValueChanged, true);
            app.PackvoltageVEditField.Value = 1;
            app.VehicleDrivingstyleTab = uitab(app.TabGroup);
            app.VehicleDrivingstyleTab.Title = 'Vehicle & Driving style';
            app.KerbweightkgEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.KerbweightkgEditFieldLabel.Text = 'Kerb weight(kg)';
            app.KerbweightkgEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.KerbweightkgEditField.ValueChangedFcn = createCallbackFcn(app, @KerbweightkgEditFieldValueChanged, true);
            app.KerbweightkgEditField.Value = 1;
            app.FueltankenginewtkgEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.FueltankenginewtkgEditFieldLabel.Text = 'Fuel tank + engine wt.(kg)';
            app.FueltankenginewtkgEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.FueltankenginewtkgEditField.ValueChangedFcn = createCallbackFcn(app, @FueltankenginewtkgEditFieldValueChanged, true);
            app.FueltankenginewtkgEditField.Value = 1;
            app.PassengerweightkgEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.PassengerweightkgEditFieldLabel.Text = 'Passenger weight(kg)';
            app.ReplacementmotorwtkgEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.ReplacementmotorwtkgEditField.Value = 1;
            app.PassengerweightkgEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.PassengerweightkgEditField.ValueChangedFcn = createCallbackFcn(app, @PassengerweightkgEditFieldValueChanged, true);
            app.PassengerweightkgEditField.Value = 1;
            app.NoofpassengersEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.NoofpassengersEditFieldLabel.Text = 'No. of passengers';
            app.NoofpassengersEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.NoofpassengersEditField.ValueChangedFcn = createCallbackFcn(app, @NoofpassengersEditFieldValueChanged, true);
            app.NoofpassengersEditField.Value = 1;
            app.DragcoefficientEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.DragcoefficientEditFieldLabel.Text = 'Drag coefficient';
            app.DragcoefficientEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.DragcoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @DragcoefficientEditFieldValueChanged, true);
            app.DragcoefficientEditField.Value = 1;
            app.RollingresistancecoefficientEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.RollingresistancecoefficientEditFieldLabel.Text = 'Rolling resistance coefficient';
            app.RollingresistancecoefficientEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.RollingresistancecoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @RollingresistancecoefficientEditFieldValueChanged, true);
            app.RollingresistancecoefficientEditField.Value = 1;
            app.Vehicleridercrosssectionm2EditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.Vehicleridercrosssectionm2EditFieldLabel.Text = 'Vehicle+rider cross section(m2)';
            app.Vehicleridercrosssectionm2EditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.Vehicleridercrosssectionm2EditField.ValueChangedFcn = createCallbackFcn(app, @Vehicleridercrosssectionm2EditFieldValueChanged, true);
            app.Vehicleridercrosssectionm2EditField.Value = 1;
            app.VehicleenvironmentparametersLabel = uilabel(app.VehicleDrivingstyleTab);
            app.VehicleenvironmentparametersLabel.Text = 'Vehicle & environment parameters';
            app.RoadgradientangledegEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.RoadgradientangledegEditFieldLabel.Text = 'Road gradient angle(deg)';
            app.RoadgradientangledegEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.RoadgradientangledegEditField.ValueChangedFcn = createCallbackFcn(app, @RoadgradientangledegEditFieldValueChanged, true);
            app.RoadgradientangledegEditField.Value = 1;
            app.Airdensitykgm3EditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.Airdensitykgm3EditFieldLabel.Text = 'Air density(kg/m3)';
            app.Airdensitykgm3EditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.Airdensitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @Airdensitykgm3EditFieldValueChanged, true);
            app.Airdensitykgm3EditField.Value = 1;
            app.ReplacementmotorwtkgEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.ReplacementmotorwtkgEditFieldLabel.Text = 'Replacement motor wt.(kg)';
            app.TotaldistancecoveredkmEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.TotaldistancecoveredkmEditFieldLabel.Text = 'Total distance covered(km)';
            app.TotaldistancecoveredkmEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.TotaldistancecoveredkmEditField.ValueChangedFcn = createCallbackFcn(app, @TotaldistancecoveredkmEditFieldValueChanged, true);
            app.TotaldistancecoveredkmEditField.Value = 1;
            app.ConstantspeedkmphEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.ConstantspeedkmphEditFieldLabel.Text = 'Constant speed(kmph)';
            app.ConstantspeedkmphEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.ConstantspeedkmphEditField.ValueChangedFcn = createCallbackFcn(app, @ConstantspeedkmphEditFieldValueChanged, true);
            app.ConstantspeedkmphEditField.Value = 1;
            app.TimetakentoreachtheconstantspeedsecEditFieldLabel = uilabel(app.VehicleDrivingstyleTab);
            app.TimetakentoreachtheconstantspeedsecEditFieldLabel.Text = 'Time taken to reach the constant speed(sec)';
            app.TimetakentoreachtheconstantspeedsecEditField = uieditfield(app.VehicleDrivingstyleTab, 'numeric');
            app.TimetakentoreachtheconstantspeedsecEditField.ValueChangedFcn = createCallbackFcn(app, @TimetakentoreachtheconstantspeedsecEditFieldValueChanged, true);
            app.TimetakentoreachtheconstantspeedsecEditField.Value = 1;
            app.SymmetricaltrapeziumvtcurvecharacteristicsLabel = uilabel(app.VehicleDrivingstyleTab);
            app.SymmetricaltrapeziumvtcurvecharacteristicsLabel.Text = 'Symmetrical trapezium v-t curve characteristics';
            app.ParameterslidersTab = uitab(app.TabGroup);
            app.ParameterslidersTab.Title = 'Parameter sliders';
            app.VelocitykmphSliderLabel = uilabel(app.ParameterslidersTab);
            app.VelocitykmphSliderLabel.Text = 'Velocity(kmph)';
            app.VelocitykmphSlider = uislider(app.ParameterslidersTab);
            app.VelocitykmphSlider.ValueChangingFcn = createCallbackFcn(app, @VelocitykmphSliderValueChanging, true);
            app.VelocitykmphSlider.Value = 10;
            app.PackvoltageVSliderLabel = uilabel(app.ParameterslidersTab);
            app.PackvoltageVSliderLabel.Text = 'Pack voltage(V)';
            app.PackvoltageVSlider = uislider(app.ParameterslidersTab);
            app.PackvoltageVSlider.Limits = [10 100];
            app.PackvoltageVSlider.ValueChangingFcn = createCallbackFcn(app, @PackvoltageVSliderValueChanging, true);
            app.PackvoltageVSlider.Value = 10;
            app.CellCrateSliderLabel = uilabel(app.ParameterslidersTab);
            app.CellCrateSliderLabel.Text = 'Cell C-rate';
            app.CellCrateSlider = uislider(app.ParameterslidersTab);
            app.CellCrateSlider.Limits = [1 10];
            app.CellCrateSlider.ValueChangingFcn = createCallbackFcn(app, @CellCrateSliderValueChanging, true);
            app.CellCrateSlider.Value = 1;
            app.CellvoltageVSliderLabel = uilabel(app.ParameterslidersTab);
            app.CellvoltageVSliderLabel.Text = 'Cell voltage(V)';
            app.CellvoltageVSlider = uislider(app.ParameterslidersTab);
            app.CellvoltageVSlider.Limits = [1 6];
            app.CellvoltageVSlider.ValueChangingFcn = createCallbackFcn(app, @CellvoltageVSliderValueChanging, true);
            app.CellvoltageVSlider.Value = 1;
            app.CellcapacitymAhSliderLabel = uilabel(app.ParameterslidersTab);
            app.CellcapacitymAhSliderLabel.Text = 'Cell capacity(mAh)';
            app.CellcapacitymAhSlider = uislider(app.ParameterslidersTab);
            app.CellcapacitymAhSlider.Limits = [1000 6000];
            app.CellcapacitymAhSlider.ValueChangingFcn = createCallbackFcn(app, @CellcapacitymAhSliderValueChanging, true);
            app.CellcapacitymAhSlider.Value = 1000;
            app.INPUTPARAMETERSLabel = uilabel(app.EVcalculatorUIFigure);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.RESULTSLabel = uilabel(app.EVcalculatorUIFigure);
            app.RESULTSLabel.Text = 'RESULTS';
            app.TabGroup2 = uitabgroup(app.EVcalculatorUIFigure);
            app.EnergyconsumptionTab = uitab(app.TabGroup2);
            app.EnergyconsumptionTab.Title = 'Energy consumption';
            app.GrossvehicleweightkgEditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.GrossvehicleweightkgEditFieldLabel.Text = 'Gross vehicle weight(kg)';
            app.GrossvehicleweightkgEditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.GrossvehicleweightkgEditField.Editable = 'off';
            app.GrossvehicleweightkgEditField.Value = 1;
            app.Constantaccdecms2EditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.Constantaccdecms2EditFieldLabel.Visible = 'off';
            app.Constantaccdecms2EditFieldLabel.Text = 'Constant acc./dec.(m/s^2)';
            app.Constantaccdecms2EditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.Constantaccdecms2EditField.Editable = 'off';
            app.Constantaccdecms2EditField.Visible = 'off';
            app.Constantaccdecms2EditField.Value = 1;
            app.DistancecoveredduringaccdeckmEditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.DistancecoveredduringaccdeckmEditFieldLabel.Visible = 'off';
            app.DistancecoveredduringaccdeckmEditFieldLabel.Text = 'Distance covered during acc./dec.(km)';
            app.DistancecoveredduringaccdeckmEditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.DistancecoveredduringaccdeckmEditField.Editable = 'off';
            app.DistancecoveredduringaccdeckmEditField.Visible = 'off';
            app.DistancecoveredduringaccdeckmEditField.Value = 1;
            app.EnergyconsumptionduringaccWhEditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.EnergyconsumptionduringaccWhEditFieldLabel.Text = 'Energy consumption during acc.(Wh)';
            app.EnergyconsumptionduringaccWhEditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.EnergyconsumptionduringaccWhEditField.Editable = 'off';
            app.EnergyconsumptionduringaccWhEditField.Value = 1;
            app.EnergyconsumptionduringconstvelWhEditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.EnergyconsumptionduringconstvelWhEditFieldLabel.Text = 'Energy consumption during const. vel.(Wh)';
            app.EnergyconsumptionduringconstvelWhEditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.EnergyconsumptionduringconstvelWhEditField.Editable = 'off';
            app.EnergyconsumptionduringconstvelWhEditField.Value = 1;
            app.AuxillaryenergyconsumptionWhEditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.AuxillaryenergyconsumptionWhEditFieldLabel.Text = 'Auxillary energy consumption(Wh)';
            app.AuxillaryenergyconsumptionWhEditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.AuxillaryenergyconsumptionWhEditField.Editable = 'off';
            app.AuxillaryenergyconsumptionWhEditField.Value = 1;
            app.AverageenergyconsumptionkmWhkmEditFieldLabel = uilabel(app.EnergyconsumptionTab);
            app.AverageenergyconsumptionkmWhkmEditFieldLabel.Text = 'Average energy consumption/km(Wh/km)';
            app.AverageenergyconsumptionkmWhkmEditField = uieditfield(app.EnergyconsumptionTab, 'numeric');
            app.AverageenergyconsumptionkmWhkmEditField.Editable = 'off';
            app.AverageenergyconsumptionkmWhkmEditField.Value = 1;
            app.BatterypackTab = uitab(app.TabGroup2);
            app.BatterypackTab.Title = 'Battery pack';
            app.NoofcellsinseriesEditFieldLabel = uilabel(app.BatterypackTab);
            app.NoofcellsinseriesEditFieldLabel.Text = 'No. of cells in series';
            app.NoofcellsinseriesEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.NoofcellsinseriesEditField.Editable = 'off';
            app.NoofcellsinseriesEditField.Value = 1;
            app.PacklengthmmEditFieldLabel = uilabel(app.BatterypackTab);
            app.PacklengthmmEditFieldLabel.Text = 'Pack length(mm)';
            app.PacklengthmmEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.PacklengthmmEditField.Editable = 'off';
            app.PacklengthmmEditField.Value = 1;
            app.TotalcellsEditFieldLabel = uilabel(app.BatterypackTab);
            app.TotalcellsEditFieldLabel.Text = 'Total cells';
            app.TotalcellsEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.TotalcellsEditField.Editable = 'off';
            app.TotalcellsEditField.Value = 1;
            app.NoofparallelrowsEditFieldLabel = uilabel(app.BatterypackTab);
            app.NoofparallelrowsEditFieldLabel.Text = 'No. of parallel rows';
            app.NoofparallelrowsEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.NoofparallelrowsEditField.Editable = 'off';
            app.NoofparallelrowsEditField.Value = 1;
            app.PackwidthmmEditFieldLabel = uilabel(app.BatterypackTab);
            app.PackwidthmmEditFieldLabel.Text = 'Pack width(mm)';
            app.PackwidthmmEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.PackwidthmmEditField.Editable = 'off';
            app.PackwidthmmEditField.Value = 1;
            app.Packaream2EditFieldLabel = uilabel(app.BatterypackTab);
            app.Packaream2EditFieldLabel.Text = 'Pack area(m2)';
            app.Packaream2EditField = uieditfield(app.BatterypackTab, 'numeric');
            app.Packaream2EditField.Editable = 'off';
            app.Packaream2EditField.Value = 1;
            app.PackheigthmmEditFieldLabel = uilabel(app.BatterypackTab);
            app.PackheigthmmEditFieldLabel.Text = 'Pack heigth(mm)';
            app.PackheigthmmEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.PackheigthmmEditField.Editable = 'off';
            app.PackheigthmmEditField.Value = 1;
            app.PackweightkgEditFieldLabel = uilabel(app.BatterypackTab);
            app.PackweightkgEditFieldLabel.Text = 'Pack weight(kg)';
            app.PackweightkgEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.PackweightkgEditField.Editable = 'off';
            app.PackweightkgEditField.Value = 1;
            app.AvailablePackcapacityAhEditFieldLabel = uilabel(app.BatterypackTab);
            app.AvailablePackcapacityAhEditFieldLabel.Text = 'Available Pack capacity(Ah)';
            app.AvailablePackcapacityAhEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.AvailablePackcapacityAhEditField.Editable = 'off';
            app.AvailablePackcapacityAhEditField.Value = 1;
            app.AvailablePackvoltageVEditFieldLabel = uilabel(app.BatterypackTab);
            app.AvailablePackvoltageVEditFieldLabel.Text = 'Available Pack voltage(V) ';
            app.AvailablePackvoltageVEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.AvailablePackvoltageVEditField.Editable = 'off';
            app.AvailablePackvoltageVEditField.Value = 1;
            app.AvailablePackenergykWhEditFieldLabel = uilabel(app.BatterypackTab);
            app.AvailablePackenergykWhEditFieldLabel.Text = 'Available Pack energy(kWh)';
            app.AvailablePackenergykWhEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.AvailablePackenergykWhEditField.Editable = 'off';
            app.AvailablePackenergykWhEditField.Value = 1;
            app.AvailablePackpowerkWEditFieldLabel = uilabel(app.BatterypackTab);
            app.AvailablePackpowerkWEditFieldLabel.Text = 'Available Pack power(kW)';
            app.AvailablePackpowerkWEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.AvailablePackpowerkWEditField.Editable = 'off';
            app.AvailablePackpowerkWEditField.Value = 1;
            app.PackheatgenerationWEditFieldLabel = uilabel(app.BatterypackTab);
            app.PackheatgenerationWEditFieldLabel.Text = 'Pack heat generation(W)';
            app.PackheatgenerationWEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.PackheatgenerationWEditField.Editable = 'off';
            app.PackheatgenerationWEditField.Value = 1;
            app.RangeKmEditFieldLabel = uilabel(app.BatterypackTab);
            app.RangeKmEditFieldLabel.Text = 'Range(Km)';
            app.RangeKmEditField = uieditfield(app.BatterypackTab, 'numeric');
            app.RangeKmEditField.Editable = 'off';
            app.RangeKmEditField.Value = 1;
            app.ParametersliderinfluenceTab = uitab(app.TabGroup2);
            app.ParametersliderinfluenceTab.Title = 'Parameter slider influence';
            app.RangekmGaugeLabel = uilabel(app.ParametersliderinfluenceTab);
            app.RangekmGaugeLabel.Text = 'Range(km)';
            app.RangekmGauge = uigauge(app.ParametersliderinfluenceTab, 'linear');
            app.RangekmGauge.Limits = [0 200];
            app.PackheatgenerationWGaugeLabel = uilabel(app.ParametersliderinfluenceTab);
            app.PackheatgenerationWGaugeLabel.Text = 'Pack heat generation(W)';
            app.PackheatgenerationWGauge = uigauge(app.ParametersliderinfluenceTab, 'linear');
            app.PackheatgenerationWGauge.Limits = [0 3000];
            app.PackweightkgGaugeLabel = uilabel(app.ParametersliderinfluenceTab);
            app.PackweightkgGaugeLabel.Text = 'Pack weight(kg)';
            app.PackweightkgGauge = uigauge(app.ParametersliderinfluenceTab, 'linear');
            app.PackweightkgGauge.Limits = [0 30];
            app.TotalcellsGaugeLabel = uilabel(app.ParametersliderinfluenceTab);
            app.TotalcellsGaugeLabel.Text = 'Total cells';
            app.TotalcellsGauge = uigauge(app.ParametersliderinfluenceTab, 'linear');
            app.TotalcellsGauge.Limits = [0 1500];
            % Show the figure after all components are created
            app.EVcalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EV_calculator_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EVcalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EVcalculatorUIFigure)
        end
    end
end